classdef histoCAT_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        Load_Menu                matlab.ui.container.Menu
        Load_Samples             matlab.ui.container.Menu
        load_sessionData         matlab.ui.container.Menu
        savemenuoptions          matlab.ui.container.Menu
        Savetiff_fig             matlab.ui.container.Menu
        SavePlot                 matlab.ui.container.Menu
        ExportCSV                matlab.ui.container.Menu
        export_fcs               matlab.ui.container.Menu
        uitoolbar1               matlab.ui.container.Toolbar
        uipushtool2              matlab.ui.container.toolbar.PushTool
        uitoggletool1            matlab.ui.container.toolbar.ToggleTool
        uitoggletool2            matlab.ui.container.toolbar.ToggleTool
        uitoggletool3            matlab.ui.container.toolbar.ToggleTool
        uitoggletool4            matlab.ui.container.toolbar.ToggleTool
        uitoggletool5            matlab.ui.container.toolbar.ToggleTool
        median                   matlab.ui.control.CheckBox
        pixelexpansion_dropdown  matlab.ui.control.DropDown
        Panel_right              matlab.ui.container.Panel
        panel_heatmap            matlab.ui.container.Panel
        list_heatmap             matlab.ui.control.ListBox
        uipanel20                matlab.ui.container.Panel
        panel_visualize          matlab.ui.container.Panel
        list_visual              matlab.ui.control.ListBox
        uipanel18                matlab.ui.container.Panel
        list_channels            matlab.ui.control.ListBox
        list_samples             matlab.ui.control.ListBox
        text16                   matlab.ui.control.Label
        text15                   matlab.ui.control.Label
        b2r                      matlab.ui.control.CheckBox
        Regressionline           matlab.ui.control.CheckBox
        panel_plots              matlab.ui.container.Panel
        panel_tiff_images        matlab.ui.container.Panel
        mask_onoff               matlab.ui.control.CheckBox
        areaxy_onoff             matlab.ui.control.CheckBox
        preparesample_button     matlab.ui.control.Button
        preparesample_options    matlab.ui.control.DropDown
        visualize_button         matlab.ui.control.Button
        visualize_options        matlab.ui.control.DropDown
        analyze_options          matlab.ui.control.DropDown
        text5                    matlab.ui.control.Label
        text4                    matlab.ui.control.Label
        analyze_button           matlab.ui.control.Button
        remove_options           matlab.ui.control.Button
        Untitled_3               matlab.ui.container.ContextMenu
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.WindowButtonMotionFcn = 'UNKNOWN Function';
            app.uipushtool2.ClickedCallback = 'histoCAT(''save_sessiondata'')';
            app.uitoggletool1.ClickedCallback = 'putdowntext(''zoomin'',gcbo)';
            app.uitoggletool2.ClickedCallback = 'putdowntext(''zoomout'',gcbo)';
            app.uitoggletool3.ClickedCallback = 'putdowntext(''rotate3d'',gcbo)';
            app.uitoggletool4.ClickedCallback = 'putdowntext(''pan'',gcbo)';
            app.uitoggletool5.ClickedCallback = 'putdowntext(''datatip'',gcbo)';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function histoCAT_OpeningFcn(app, varargin)
            % --- Executes just before histoCAT is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to histoCAT (see VARARGIN)
            
            % add log file
            % currentFolder = pwd;
            % diary_file = fullfile(currentFolder, 'compiled_app_log.txt')
            % diary(diary_file)
            
            % Choose default command line output for histoCAT
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            setappdata(0,'histoCATgui',gcf);
            
            %Switch off all the uicontrol not necessary before loading samples
            set(handles.analyze_button,'Enable','off');
            set(handles.visualize_button,'Enable','off');
            set(handles.preparesample_button,'Enable','off');
            set(handles.remove_options,'Enable','off');
            set(handles.areaxy_onoff,'Visible','off');
            set(handles.mask_onoff,'Visible','off');
            set(handles.areaxy_onoff,'Callback',@areaxy_checkbox_Callback);
            
            %%Clear all global variables
            clearvars -global
            
            loadflag = 1;
            setappdata(hObject, 'loadflag', loadflag);
            
            % Version number
            handles.ThisVersion='1.77';
            
            % Include git information
            %gitInfo=getGitInfo();
            % if no git file included
            %if isempty(gitInfo) == 1
            %    set(handles.figure1, 'Name', ['histoCAT_',handles.ThisVersion]);
            %else
            %    set(handles.figure1, 'Name', ['histoCAT_',handles.ThisVersion,'  ',gitInfo.hash]);
            %end
            set(handles.figure1, 'Name', ['histoCAT_',handles.ThisVersion]);
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Menu selected function: Load_Samples
        function Load_SamplesMenuSelected(app, event)
            addpath("histoCAT/Loading_New/")
            Master_LoadSamples(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'histoCAT';
            app.figure1.Tag = 'figure1';
            app.Load_Menu = uimenu(app.figure1);
            app.Load_Menu.Text = 'Load';
            app.Load_Menu.Tag = 'Load_Menu';
            app.Load_Samples = uimenu(app.Load_Menu);
            app.Load_Samples.MenuSelectedFcn = createCallbackFcn(app, @Load_SamplesMenuSelected, true);
            app.Load_Samples.Text = 'Load Samples';
            app.Load_Samples.Tag = 'Load_Samples';
            app.load_sessionData = uimenu(app.Load_Menu);
            app.load_sessionData.Text = 'Load Session Data';
            app.load_sessionData.Tag = 'load_sessionData';
            app.savemenuoptions = uimenu(app.figure1);
            app.savemenuoptions.Text = 'Save';
            app.savemenuoptions.Tag = 'savemenuoptions';
            app.Savetiff_fig = uimenu(app.savemenuoptions);
            app.Savetiff_fig.Text = 'Save Tiff figure';
            app.Savetiff_fig.Tag = 'Savetiff_fig';
            app.SavePlot = uimenu(app.savemenuoptions);
            app.SavePlot.Text = 'Save Plots';
            app.SavePlot.Tag = 'SavePlot';
            app.ExportCSV = uimenu(app.savemenuoptions);
            app.ExportCSV.Text = 'Export gates as CSV';
            app.ExportCSV.Tag = 'ExportCSV';
            app.export_fcs = uimenu(app.savemenuoptions);
            app.export_fcs.Text = 'Export fcs';
            app.export_fcs.Tag = 'export_fcs';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uipushtool2 = uipushtool(app.uitoolbar1);
            app.uipushtool2.Tag = 'uipushtool2';
            app.uipushtool2.Tooltip = 'Save Session Data';
            app.uipushtool2.Icon = 'uipushtool2_image.png';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Zoom In';
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom Out';
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Rotate 3D';
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Pan';
            app.uitoggletool4.Icon = 'uitoggletool4_image.png';
            app.uitoggletool5 = uitoggletool(app.uitoolbar1);
            app.uitoggletool5.Tag = 'uitoggletool5';
            app.uitoggletool5.Tooltip = 'Data Cursor';
            app.uitoggletool5.Icon = 'uitoggletool5_image.png';
            app.remove_options = uibutton(app.figure1, 'push');
            app.remove_options.Tag = 'remove_options';
            app.remove_options.Text = 'Remove/Clear';
            app.analyze_button = uibutton(app.figure1, 'push');
            app.analyze_button.Tag = 'analyze_button';
            app.analyze_button.Text = 'Analyze';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Visualization';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Analysis';
            app.analyze_options = uidropdown(app.figure1);
            app.analyze_options.Items = {'Options', 'Scatter', 'Histogram', 'Boxplot', 'Heatmap', 'Run t-SNE', 'Run PCA', 'Run Phenograph', 'Run k-means', 'Gate on plot', 'Highlight sample on Plot'};
            app.analyze_options.Tag = 'analyze_options';
            app.analyze_options.Value = 'Options';
            app.visualize_options = uidropdown(app.figure1);
            app.visualize_options.Items = {'View Options', 'Gate an area on Tiff image', 'Highlight sample on Tiff images', 'Apply RGBCMY on selected samples', 'Heatmap channel on selected samples', 'Highlight excluding selected sample'};
            app.visualize_options.Tag = 'visualize_options';
            app.visualize_options.Value = 'View Options';
            app.visualize_button = uibutton(app.figure1, 'push');
            app.visualize_button.Tag = 'visualize_button';
            app.visualize_button.Text = 'Visualize';
            app.preparesample_options = uidropdown(app.figure1);
            app.preparesample_options.Items = {'Sample options', 'Import Neighbors', 'Merge Samples', 'Normalization', 'Neighborhood Analysis', 'Custom Clustering'};
            app.preparesample_options.Tag = 'preparesample_options';
            app.preparesample_options.Value = 'Sample options';
            app.preparesample_button = uibutton(app.figure1, 'push');
            app.preparesample_button.Tag = 'preparesample_button';
            app.preparesample_button.Text = 'Prepare';
            app.areaxy_onoff = uicheckbox(app.figure1);
            app.areaxy_onoff.Tag = 'areaxy_onoff';
            app.areaxy_onoff.Tooltip = 'Visualize channels along with area shape XY plot';
            app.areaxy_onoff.Text = 'Plot sample area XY(on/off)';
            app.mask_onoff = uicheckbox(app.figure1);
            app.mask_onoff.Tag = 'mask_onoff';
            app.mask_onoff.Tooltip = 'Visualize mask on/off on the current tiff image';
            app.mask_onoff.Text = 'Mask(on/off)';
            app.panel_tiff_images = uipanel(app.figure1);
            app.panel_tiff_images.Tag = 'panel_tiff_images';
            app.panel_plots = uipanel(app.figure1);
            app.panel_plots.Tag = 'panel_plots';
            app.Regressionline = uicheckbox(app.figure1);
            app.Regressionline.Tag = 'Regressionline';
            app.Regressionline.Visible = 'off';
            app.Regressionline.Text = {'Regressionline'; ''};
            app.b2r = uicheckbox(app.figure1);
            app.b2r.Tag = 'b2r';
            app.b2r.Visible = 'off';
            app.b2r.Text = {'b2r'; ''};
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Samples/ Populations';
            app.text16 = uilabel(app.figure1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Channels';
            app.Panel_right = uipanel(app.figure1);
            app.Panel_right.Tag = 'Panel_right';
            app.list_samples = uilistbox(app.Panel_right);
            app.list_samples.Items = {'Sample Names'};
            app.list_samples.Tag = 'list_samples';
            app.list_samples.Value = 'Sample Names';
            app.list_channels = uilistbox(app.Panel_right);
            app.list_channels.Items = {'Channel Names'};
            app.list_channels.Tag = 'list_channels';
            app.list_channels.Value = 'Channel Names';
            app.panel_visualize = uipanel(app.Panel_right);
            app.panel_visualize.Title = 'Source Images';
            app.panel_visualize.Tag = 'panel_visualize';
            app.uipanel18 = uipanel(app.panel_visualize);
            app.uipanel18.Title = 'Visualize Samples ';
            app.uipanel18.Tag = 'uipanel18';
            app.list_visual = uilistbox(app.panel_visualize);
            app.list_visual.Items = {};
            app.list_visual.Tag = 'list_visual';
            app.list_visual.Value = {};
            app.panel_heatmap = uipanel(app.Panel_right);
            app.panel_heatmap.Title = 'Heatmap Channels';
            app.panel_heatmap.Tag = 'panel_heatmap';
            app.uipanel20 = uipanel(app.panel_heatmap);
            app.uipanel20.Title = 'Visualize Samples ';
            app.uipanel20.Tag = 'uipanel20';
            app.list_heatmap = uilistbox(app.panel_heatmap);
            app.list_heatmap.Items = {};
            app.list_heatmap.Tag = 'list_heatmap';
            app.list_heatmap.Value = {};
            app.pixelexpansion_dropdown = uidropdown(app.figure1);
            app.pixelexpansion_dropdown.Items = {'Pixel Expansion Neighbours'};
            app.pixelexpansion_dropdown.Tag = 'pixelexpansion_dropdown';
            app.pixelexpansion_dropdown.Value = 'Pixel Expansion Neighbours';
            app.median = uicheckbox(app.figure1);
            app.median.Tag = 'median';
            app.median.Visible = 'off';
            app.median.Text = {'median instead of mean'; '   '};
            app.Untitled_3 = uicontextmenu(app.figure1);
            app.Untitled_3.Tag = 'Untitled_3';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = histoCAT_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)histoCAT_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end